classdef InterfazGraficaBombas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label                       matlab.ui.control.Label
        Caudal1                     matlab.ui.control.NumericEditField
        EcuacincaractersticabombasLabel  matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Caudal2                     matlab.ui.control.NumericEditField
        Caudal3                     matlab.ui.control.NumericEditField
        Label_3                     matlab.ui.control.Label
        Cabezal1                    matlab.ui.control.NumericEditField
        Label_4                     matlab.ui.control.Label
        Cabezal2                    matlab.ui.control.NumericEditField
        Label_5                     matlab.ui.control.Label
        Cabezal3                    matlab.ui.control.NumericEditField
        CaudallsLabel               matlab.ui.control.Label
        CabezalmLabel               matlab.ui.control.Label
        NoLabel                     matlab.ui.control.Label
        Label_6                     matlab.ui.control.Label
        Label_7                     matlab.ui.control.Label
        Label_8                     matlab.ui.control.Label
        NPSHEditFieldLabel          matlab.ui.control.Label
        NPSH                        matlab.ui.control.NumericEditField
        UIAxes                      matlab.ui.control.UIAxes
        CalcularcoeficientesButton  matlab.ui.control.Button
        AEditFieldLabel             matlab.ui.control.Label
        A                           matlab.ui.control.NumericEditField
        GraficarcoeficientesenloscaudalesindicadosButton  matlab.ui.control.Button
        BEditFieldLabel             matlab.ui.control.Label
        B                           matlab.ui.control.NumericEditField
        CEditFieldLabel             matlab.ui.control.Label
        C                           matlab.ui.control.NumericEditField
        EcuacionCaracteristica      matlab.ui.control.Label
        Q_minEditFieldLabel         matlab.ui.control.Label
        Q_min                       matlab.ui.control.NumericEditField
        InformacinsobreelcaudaldelagrficaLabel  matlab.ui.control.Label
        Q_maxEditFieldLabel         matlab.ui.control.Label
        Q_max                       matlab.ui.control.NumericEditField
        Q_interseccionSliderLabel   matlab.ui.control.Label
        Q_interseccion              matlab.ui.control.Slider
        EcuacionCaracteristica_2    matlab.ui.control.Label
        DatosLabel                  matlab.ui.control.Label
        GrficaLabel                 matlab.ui.control.Label
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EcuacionCaracteristica.Text = ['H = AQ', char(178), ' + BQ + C' ];
        end

        % Button pushed function: CalcularcoeficientesButton
        function CalcularcoeficientesButtonPushed(app, event)
            VectorCaudal = [app.Caudal1.Value; app.Caudal2.Value; app.Caudal3.Value];
            VectorCaudal = VectorCaudal./1000;
            
            VectorCabezal = [app.Cabezal1.Value; app.Cabezal2.Value; app.Cabezal3.Value];
            
            [app.A.Value, app.B.Value, app.C.Value] = CalcularCoeficientes(VectorCaudal, VectorCabezal);
            
            
            Astr = num2str(app.A.Value);
            Bstr = num2str(app.B.Value);
            Cstr = num2str(app.C.Value);
            app.EcuacionCaracteristica.Text = ['H = ', Astr, 'Q', char(178), ' + ' Bstr, 'Q + ', Cstr];
            
            app.Q_min.Value = 0;
            app.Q_max.Value = max(roots([app.A.Value, app.B.Value, app.C.Value]));
            
            app.Q_min.Limits = [0, Inf];
            app.Q_max.Limits = [app.Q_min.Value, Inf];
            if app.Q_min.Value ~= app.Q_max.Value
                app.Q_interseccion.Limits = [app.Q_min.Value, app.Q_max.Value];
            else
                app.Q_interseccion.Limits = [app.Q_min.Value, app.Q_max.Value+.01];
            end
            
            app.Q_interseccion.Value = mean(app.Q_interseccion.Limits);
        end

        % Button pushed function: 
        % GraficarcoeficientesenloscaudalesindicadosButton
        function GraficarcoeficientesenloscaudalesindicadosButtonPushed(app, event)
            app.UIAxes.cla;
            ax = app.UIAxes;
            
            A = app.A.Value;
            B = app.B.Value;
            C = app.C.Value;
            
            Qmin = app.Q_min.Value;
            Qmax = app.Q_max.Value;
            
            NPSH = app.NPSH.Value;

            VectorQ=linspace(Qmin,Qmax,250);
            VectorH=FuncionCargaH(A,B,C,VectorQ);
            
            QEsp = app.Q_interseccion.Value;
            kAprox = A + B/QEsp + C/QEsp^2 - NPSH/QEsp^2;
            
            VectorHDucto = funcionCaracteristicaDuctos (kAprox,VectorQ, NPSH);
            
            plot(ax, VectorQ,VectorH, VectorQ, VectorHDucto, 'linewidth',2);
            
            title(ax,"Ecuación carácteristica de bomba y sistema de ductos", 'fontsize',20)
            xlabel(ax,"Q [m^3/s]",'fontsize',12)
            ylabel(ax,"H [m]", 'fontsize',12)
            legend(ax,{'Bomba', 'Ducto'}, 'fontsize',14);

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Caudal1 = uieditfield(app.UIFigure, 'numeric');
            app.Caudal1.Value = 40;
            app.EcuacincaractersticabombasLabel = uilabel(app.UIFigure);
            app.EcuacincaractersticabombasLabel.Text = 'Ecuación característica bombas';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Caudal2 = uieditfield(app.UIFigure, 'numeric');
            app.Caudal2.Value = 100;
            app.Caudal3 = uieditfield(app.UIFigure, 'numeric');
            app.Caudal3.Value = 180;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Cabezal1 = uieditfield(app.UIFigure, 'numeric');
            app.Cabezal1.Value = 83.26;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Cabezal2 = uieditfield(app.UIFigure, 'numeric');
            app.Cabezal2.Value = 63.58;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Cabezal3 = uieditfield(app.UIFigure, 'numeric');
            app.Cabezal3.Value = 11.07;
            app.CaudallsLabel = uilabel(app.UIFigure);
            app.CaudallsLabel.Text = 'Caudal [l/s]';
            app.CabezalmLabel = uilabel(app.UIFigure);
            app.CabezalmLabel.Text = 'Cabezal [m]';
            app.NoLabel = uilabel(app.UIFigure);
            app.NoLabel.Text = 'No.';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '1';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '2';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '3';
            app.NPSHEditFieldLabel = uilabel(app.UIFigure);
            app.NPSHEditFieldLabel.Text = 'NPSH';
            app.NPSH = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CalcularcoeficientesButton = uibutton(app.UIFigure, 'push');
            app.CalcularcoeficientesButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularcoeficientesButtonPushed, true);
            app.CalcularcoeficientesButton.Text = 'Calcular coeficientes';
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A';
            app.A = uieditfield(app.UIFigure, 'numeric');
            app.GraficarcoeficientesenloscaudalesindicadosButton = uibutton(app.UIFigure, 'push');
            app.GraficarcoeficientesenloscaudalesindicadosButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarcoeficientesenloscaudalesindicadosButtonPushed, true);
            app.GraficarcoeficientesenloscaudalesindicadosButton.Text = 'Graficar coeficientes en los caudales indicados';
            app.BEditFieldLabel = uilabel(app.UIFigure);
            app.BEditFieldLabel.Text = 'B';
            app.B = uieditfield(app.UIFigure, 'numeric');
            app.CEditFieldLabel = uilabel(app.UIFigure);
            app.CEditFieldLabel.Text = 'C';
            app.C = uieditfield(app.UIFigure, 'numeric');
            app.EcuacionCaracteristica = uilabel(app.UIFigure);
            app.EcuacionCaracteristica.Text = 'Ecuación Característica de la bomba';
            app.Q_minEditFieldLabel = uilabel(app.UIFigure);
            app.Q_minEditFieldLabel.Text = 'Q_min';
            app.Q_min = uieditfield(app.UIFigure, 'numeric');
            app.Q_min.Limits = [0 Inf];
            app.InformacinsobreelcaudaldelagrficaLabel = uilabel(app.UIFigure);
            app.InformacinsobreelcaudaldelagrficaLabel.Text = 'Información sobre el caudal de la gráfica';
            app.Q_maxEditFieldLabel = uilabel(app.UIFigure);
            app.Q_maxEditFieldLabel.Text = 'Q_max';
            app.Q_max = uieditfield(app.UIFigure, 'numeric');
            app.Q_max.Limits = [0 Inf];
            app.Q_interseccionSliderLabel = uilabel(app.UIFigure);
            app.Q_interseccionSliderLabel.Text = 'Q_interseccion';
            app.Q_interseccion = uislider(app.UIFigure);
            app.EcuacionCaracteristica_2 = uilabel(app.UIFigure);
            app.EcuacionCaracteristica_2.Text = 'Ecuación Característica de la bomba';
            app.DatosLabel = uilabel(app.UIFigure);
            app.DatosLabel.Text = 'Datos';
            app.GrficaLabel = uilabel(app.UIFigure);
            app.GrficaLabel.Text = 'Gráfica';
        end
    end

    methods (Access = public)

        % Construct app
        function app = InterfazGraficaBombas

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end