classdef compresor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CALCULOPARACOMPRESOREMBOLOPISTONSIMPLEETAPASIMOPLabel  matlab.ui.control.Label
        DiametroEditFieldLabel       matlab.ui.control.Label
        DiametroEditField            matlab.ui.control.NumericEditField
        LongitudEditFieldLabel       matlab.ui.control.Label
        LongitudEditField            matlab.ui.control.NumericEditField
        mporcentualEditFieldLabel    matlab.ui.control.Label
        mporcentualEditField         matlab.ui.control.NumericEditField
        PSuccionEditFieldLabel       matlab.ui.control.Label
        PSuccionEditField            matlab.ui.control.NumericEditField
        PdescargaEditFieldLabel      matlab.ui.control.Label
        PdescargaEditField           matlab.ui.control.NumericEditField
        T1EditFieldLabel             matlab.ui.control.Label
        T1EditField                  matlab.ui.control.NumericEditField
        EficienciaindicadaEditFieldLabel  matlab.ui.control.Label
        EficienciaindicadaEditField  matlab.ui.control.NumericEditField
        EficienciamecanicaEditFieldLabel  matlab.ui.control.Label
        EficienciamecanicaEditField  matlab.ui.control.NumericEditField
        cmLabel                      matlab.ui.control.Label
        cmLabel_2                    matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        Kgfcm2Label                  matlab.ui.control.Label
        Kgfcm2Label_2                matlab.ui.control.Label
        CLabel                       matlab.ui.control.Label
        fraccionLabel                matlab.ui.control.Label
        fraccionLabel_2              matlab.ui.control.Label
        CALCULARButton               matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        m_aValor                     matlab.ui.control.Label
        m_aspiradoLabel              matlab.ui.control.Label
        mdValor                      matlab.ui.control.Label
        m_descargaLabel              matlab.ui.control.Label
        WcompresionValor             matlab.ui.control.Label
        WindicadoValor               matlab.ui.control.Label
        WcompresionLabel             matlab.ui.control.Label
        WindicadoLabel               matlab.ui.control.Label
        WmecanicoValor               matlab.ui.control.Label
        WmecanicoLabel               matlab.ui.control.Label
        wvelangularEditFieldLabel    matlab.ui.control.Label
        wvelangularEditField         matlab.ui.control.NumericEditField
        rpmLabel                     matlab.ui.control.Label
        nEditFieldLabel              matlab.ui.control.Label
        nEditField                   matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: CALCULARButton
        function CALCULARButtonPushed(app, event)
            [app.m_aValor.Text, app.mdValor.Text, app.WcompresionValor.Text, app.WindicadoValor.Text, app.WmecanicoValor.Text]=cSeSeGi(app.DiametroEditField.Value, app.LongitudEditField.Value, app.mporcentualEditField.Value, app.wvelangularEditField.Value, app.T1EditField.Value, app.PdescargaEditField.Value, app.PSuccionEditField.Value, app.EficienciaindicadaEditField.Value, app.EficienciamecanicaEditField.Value, app.nEditField.Value);
            %plot(app.UIAxes,[.5 1 1.5],[1 2 5 ],'-r');
            %fig = app.uifigure;
            %ax = uiaxes(fig);
            %ax=gca;
            %disp(app.PSuccionEditField.Value);
            app.UIAxes.cla;
            graficarUI(app.UIAxes, app.PSuccionEditField.Value, app.PdescargaEditField.Value, app.T1EditField.Value, app.nEditField.Value, app.DiametroEditField.Value, app.LongitudEditField.Value);
        end

        % Value changed function: DiametroEditField
        function DiametroEditFieldValueChanged(app, event)
            value = app.DiametroEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CALCULOPARACOMPRESOREMBOLOPISTONSIMPLEETAPASIMOPLabel = uilabel(app.UIFigure);
            app.CALCULOPARACOMPRESOREMBOLOPISTONSIMPLEETAPASIMOPLabel.Text = 'CALCULO PARA COMPRESOR EMBOLO-PISTON SIMPLE ETAPA, SIMPLE EFECTO,AIRE GAS IDEAL,PROCESO ISOTERMICO';
            app.DiametroEditFieldLabel = uilabel(app.UIFigure);
            app.DiametroEditFieldLabel.Text = 'Diametro ';
            app.DiametroEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiametroEditField.Limits = [0 Inf];
            app.DiametroEditField.ValueChangedFcn = createCallbackFcn(app, @DiametroEditFieldValueChanged, true);
            app.DiametroEditField.Value = 10;
            app.LongitudEditFieldLabel = uilabel(app.UIFigure);
            app.LongitudEditFieldLabel.Text = 'Longitud ';
            app.LongitudEditField = uieditfield(app.UIFigure, 'numeric');
            app.LongitudEditField.Limits = [0 Inf];
            app.LongitudEditField.Value = 13;
            app.mporcentualEditFieldLabel = uilabel(app.UIFigure);
            app.mporcentualEditFieldLabel.Text = 'm porcentual';
            app.mporcentualEditField = uieditfield(app.UIFigure, 'numeric');
            app.mporcentualEditField.Limits = [0 100];
            app.mporcentualEditField.Value = 3;
            app.PSuccionEditFieldLabel = uilabel(app.UIFigure);
            app.PSuccionEditFieldLabel.Text = 'PSuccion';
            app.PSuccionEditField = uieditfield(app.UIFigure, 'numeric');
            app.PSuccionEditField.Value = 1;
            app.PdescargaEditFieldLabel = uilabel(app.UIFigure);
            app.PdescargaEditFieldLabel.Text = 'Pdescarga';
            app.PdescargaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PdescargaEditField.Value = 5;
            app.T1EditFieldLabel = uilabel(app.UIFigure);
            app.T1EditFieldLabel.Text = 'T1';
            app.T1EditField = uieditfield(app.UIFigure, 'numeric');
            app.T1EditField.Value = 17;
            app.EficienciaindicadaEditFieldLabel = uilabel(app.UIFigure);
            app.EficienciaindicadaEditFieldLabel.Text = 'Eficiencia indicada';
            app.EficienciaindicadaEditField = uieditfield(app.UIFigure, 'numeric');
            app.EficienciaindicadaEditField.Limits = [0 1];
            app.EficienciaindicadaEditField.Value = 0.75;
            app.EficienciamecanicaEditFieldLabel = uilabel(app.UIFigure);
            app.EficienciamecanicaEditFieldLabel.Text = 'Eficiencia mecanica';
            app.EficienciamecanicaEditField = uieditfield(app.UIFigure, 'numeric');
            app.EficienciamecanicaEditField.Limits = [0 1];
            app.EficienciamecanicaEditField.Value = 0.85;
            app.cmLabel = uilabel(app.UIFigure);
            app.cmLabel.Text = 'cm';
            app.cmLabel_2 = uilabel(app.UIFigure);
            app.cmLabel_2.Text = 'cm';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.Kgfcm2Label = uilabel(app.UIFigure);
            app.Kgfcm2Label.Text = 'Kgf/cm^2';
            app.Kgfcm2Label_2 = uilabel(app.UIFigure);
            app.Kgfcm2Label_2.Text = 'Kgf/cm^2';
            app.CLabel = uilabel(app.UIFigure);
            app.CLabel.Text = '°C';
            app.fraccionLabel = uilabel(app.UIFigure);
            app.fraccionLabel.Text = 'fraccion';
            app.fraccionLabel_2 = uilabel(app.UIFigure);
            app.fraccionLabel_2.Text = 'fraccion';
            app.CALCULARButton = uibutton(app.UIFigure, 'push');
            app.CALCULARButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULARButtonPushed, true);
            app.CALCULARButton.Text = 'CALCULAR';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.m_aValor = uilabel(app.UIFigure);
            app.m_aValor.Text = '';
            app.m_aspiradoLabel = uilabel(app.UIFigure);
            app.m_aspiradoLabel.Text = 'm_aspirado';
            app.mdValor = uilabel(app.UIFigure);
            app.mdValor.Text = '';
            app.m_descargaLabel = uilabel(app.UIFigure);
            app.m_descargaLabel.Text = 'm_descarga';
            app.WcompresionValor = uilabel(app.UIFigure);
            app.WcompresionValor.Text = '';
            app.WindicadoValor = uilabel(app.UIFigure);
            app.WindicadoValor.Text = '';
            app.WcompresionLabel = uilabel(app.UIFigure);
            app.WcompresionLabel.Text = 'Wcompresion';
            app.WindicadoLabel = uilabel(app.UIFigure);
            app.WindicadoLabel.Text = 'Windicado';
            app.WmecanicoValor = uilabel(app.UIFigure);
            app.WmecanicoValor.Text = '';
            app.WmecanicoLabel = uilabel(app.UIFigure);
            app.WmecanicoLabel.Text = 'Wmecanico';
            app.wvelangularEditFieldLabel = uilabel(app.UIFigure);
            app.wvelangularEditFieldLabel.Text = 'w vel angular';
            app.wvelangularEditField = uieditfield(app.UIFigure, 'numeric');
            app.wvelangularEditField.Limits = [0 Inf];
            app.wvelangularEditField.Value = 150;
            app.rpmLabel = uilabel(app.UIFigure);
            app.rpmLabel.Text = 'rpm';
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.nEditField.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = compresor

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end