classdef conificaciondeagua < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        IntroduzcaDatosEcuacinAlturadeConificacindeAguahwcLabel  matlab.ui.control.Label
        ViscosidadcpEditFieldLabel     matlab.ui.control.Label
        mu_o                           matlab.ui.control.NumericEditField
        BoEditFieldLabel               matlab.ui.control.Label
        B_o                            matlab.ui.control.NumericEditField
        DensidaddelAceitegrcm3EditFieldLabel  matlab.ui.control.Label
        rho_o                          matlab.ui.control.NumericEditField
        DensidaddelAguagrcm3Label      matlab.ui.control.Label
        rho_w                          matlab.ui.control.NumericEditField
        RadiodedrenemEditFieldLabel    matlab.ui.control.Label
        r_e                            matlab.ui.control.NumericEditField
        RadiodepozomEditFieldLabel     matlab.ui.control.Label
        r_w                            matlab.ui.control.NumericEditField
        GastodeaceiteMSTBDEditFieldLabel  matlab.ui.control.Label
        q_o                            matlab.ui.control.NumericEditField
        EsbiencementadoSwitchLabel     matlab.ui.control.Label
        cementado                      matlab.ui.control.Switch
        PermeabilidadfracturaDarcyEditFieldLabel  matlab.ui.control.Label
        k_f                            matlab.ui.control.NumericEditField
        TabGroup                       matlab.ui.container.TabGroup
        GrficagastopermeabilidadalturaTab  matlab.ui.container.Tab
        GastoPermeabilidadAltura       matlab.ui.control.UIAxes
        q_o_minEditFieldLabel          matlab.ui.control.Label
        q_o_min_surf                   matlab.ui.control.NumericEditField
        q_o_maxEditFieldLabel          matlab.ui.control.Label
        q_o_max_surf                   matlab.ui.control.NumericEditField
        k_f_minEditFieldLabel          matlab.ui.control.Label
        k_f_min_surf                   matlab.ui.control.NumericEditField
        k_f_maxEditFieldLabel          matlab.ui.control.Label
        k_f_max_surf                   matlab.ui.control.NumericEditField
        GastoMSTBDLabel                matlab.ui.control.Label
        PermeabilidadfracturadaDarcyLabel  matlab.ui.control.Label
        botonGastoPermeabilidadAltura  matlab.ui.control.Button
        Switch                         matlab.ui.control.Switch
        GrficagastoTab                 matlab.ui.container.Tab
        graficaGasto                   matlab.ui.control.UIAxes
        GastoMSTBDLabel_2              matlab.ui.control.Label
        q_o_minEditField_2Label        matlab.ui.control.Label
        q_o_min_gasto                  matlab.ui.control.NumericEditField
        q_o_maxEditField_2Label        matlab.ui.control.Label
        q_o_max_gasto                  matlab.ui.control.NumericEditField
        botonGraficaGasto              matlab.ui.control.Button
        GrficapermeabilidadTab         matlab.ui.container.Tab
        graficaPermeabilidad           matlab.ui.control.UIAxes
        PermeabilidadfracturadaDarcyLabel_2  matlab.ui.control.Label
        k_f_minEditField_2Label        matlab.ui.control.Label
        k_f_min_permeabilidad          matlab.ui.control.NumericEditField
        k_f_maxEditField_2Label        matlab.ui.control.Label
        k_f_max_permeabilidad          matlab.ui.control.NumericEditField
        botonGraficaPermeabilidad      matlab.ui.control.Button
        Calcularh_wcButton             matlab.ui.control.Button
        h_wcEditFieldLabel             matlab.ui.control.Label
        h_wc                           matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Value changed function: mu_o
        function mu_oValueChanged(app, event)
            value = app.mu_o.Value;
            
        end

        % Button pushed function: botonGraficaGasto
        function botonGraficaGastoPushed(app, event)
            app.graficaGasto.cla;
            ax = app.graficaGasto;
            
            q_o_vector = linspace( app.q_o_min_gasto.Value, app.q_o_max_gasto.Value, 100 );
            B_o = app.B_o.Value;
            mu_o = app.mu_o.Value;
            r_e = app.r_e.Value;
            r_w = app.r_w.Value;
            k_f = app.k_f.Value;
            rho_o = app.rho_o.Value;
            rho_w = app.rho_w.Value;
            rho_diferencia = deltaDensidades( rho_o, rho_w ) ;
            cementado = app.cementado.Value == 'si';
            
            n = length( q_o_vector );
            h_wc_vector = zeros( n, 1 );
            
            for i = 1: n
                [ h_wc_vector( i ) ] = alturaConoAguaMaximo( q_o_vector(i), B_o, mu_o, r_e, r_w, k_f, rho_diferencia, cementado);
            end

            plot( ax, q_o_vector, h_wc_vector, 'linewidth',2);
            
            title(ax, 'Gasto contra altura de conificación.', 'fontsize', 20)
            xlabel(ax,"Gasto [MSTB/D]",'fontsize',12)
            ylabel(ax,"Altura de conificación [metros]", 'fontsize',12)
        end

        % Button pushed function: botonGraficaPermeabilidad
        function botonGraficaPermeabilidadButtonPushed(app, event)

            app.graficaPermeabilidad.cla;
            ax = app.graficaPermeabilidad;
            
            q_o = app.q_o.Value;
            k_f_vector = linspace( app.k_f_min_permeabilidad.Value, app.k_f_max_permeabilidad.Value, 100);
            B_o = app.B_o.Value;
            mu_o = app.mu_o.Value;
            r_e = app.r_e.Value;
            r_w = app.r_w.Value;
            rho_o = app.rho_o.Value;
            rho_w = app.rho_w.Value;
            rho_diferencia = deltaDensidades( rho_o, rho_w ) ;
            cementado = app.cementado.Value == "Si";

            
            n = length( k_f_vector );
            h_wc_vector = zeros( n, 1 );
            
            for i = 1: n
                [ h_wc_vector( i ) ] = alturaConoAguaMaximo( q_o, B_o, mu_o, r_e, r_w, k_f_vector(i), rho_diferencia, cementado);
            end

            plot( ax, k_f_vector, h_wc_vector, 'linewidth',2);
            
            title(ax, 'Permeabilidad contra altura de conificación.', 'fontsize', 20)
            xlabel(ax, "Permeabilidad [Darcy]", 'fontsize', 12)
            ylabel(ax, "Altura de conificación [metros]", 'fontsize', 12)
        end

        % Button pushed function: botonGastoPermeabilidadAltura
        function botonGastoPermeabilidadAlturaButtonPushed(app, event)
            app.GastoPermeabilidadAltura.cla;
            ax = app.GastoPermeabilidadAltura;
            
            q_o_vector = linspace( app.q_o_min_surf.Value, app.q_o_max_surf.Value, 100 );
            k_f_vector = linspace( app.k_f_min_surf.Value, app.k_f_max_surf.Value, 100);
            B_o = app.B_o.Value;
            mu_o = app.mu_o.Value;
            r_e = app.r_e.Value;
            r_w = app.r_w.Value;
            rho_o = app.rho_o.Value;
            rho_w = app.rho_w.Value;
            rho_diferencia = deltaDensidades( rho_o, rho_w ) ;
            cementado = app.cementado.Value == "Si";

            
            n = length(q_o_vector);
            h_wc_vector = zeros( n, n );
            
            for i = 1: n
            	for j = 1: n
            		[ h_wc_vector( j, i ) ] = alturaConoAguaMaximo( q_o_vector(i), B_o, mu_o, r_e, r_w, k_f_vector(j), rho_diferencia, cementado);
            	end
            end
            
            for j = 1 : n
                if app.Switch.Value == "Gasto"
                    plot( ax, q_o_vector(:), h_wc_vector( j, :), 'linewidth',2);
                    xlabel(ax,"Gasto [MSTB/D]",'fontsize',12);
                    titulo = [ 'Gasto contra altura de conificación. k_f = ', num2str( k_f_vector( j ) ) ];
                else
                    plot( ax, k_f_vector(:), h_wc_vector( :, j), 'linewidth',2);
                    xlabel(ax, "Permeabilidad [Darcy]", 'fontsize', 12)
                    titulo = [ 'Gasto contra altura de conificación. q_o = ', num2str( q_o_vector( j ) ) ];
                end
            	
            	
            	title(ax, titulo, 'fontsize', 20);
            	
            	ylabel(ax, "Altura de conificación [metros]", 'fontsize', 12);
            	% axis equal;
            	% axis([ 0 100 0 1000]);
                pause(.1)
            end
        end

        % Button pushed function: Calcularh_wcButton
        function Calcularh_wcButtonPushed(app, event)
            q_o = app.q_o.Value;
            k_f = app.k_f.Value;
            B_o = app.B_o.Value;
            mu_o = app.mu_o.Value;
            r_e = app.r_e.Value;
            r_w = app.r_w.Value;
            rho_o = app.rho_o.Value;
            rho_w = app.rho_w.Value;
            rho_diferencia = deltaDensidades( rho_o, rho_w ) ;
            cementado = app.cementado.Value == "Si";
            
            [ h_wc ] = alturaConoAguaMaximo( q_o, B_o, mu_o, r_e, r_w, k_f, rho_diferencia, cementado );
            app.h_wc.Value = h_wc;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.IntroduzcaDatosEcuacinAlturadeConificacindeAguahwcLabel = uilabel(app.UIFigure);
            app.IntroduzcaDatosEcuacinAlturadeConificacindeAguahwcLabel.Text = 'Introduzca Datos Ecuación Altura de Conificación de Agua  hwc';
            app.ViscosidadcpEditFieldLabel = uilabel(app.UIFigure);
            app.ViscosidadcpEditFieldLabel.Text = 'Viscosidad [cp]';
            app.mu_o = uieditfield(app.UIFigure, 'numeric');
            app.mu_o.ValueChangedFcn = createCallbackFcn(app, @mu_oValueChanged, true);
            app.mu_o.Value = 15;
            app.BoEditFieldLabel = uilabel(app.UIFigure);
            app.BoEditFieldLabel.Text = 'Bo';
            app.B_o = uieditfield(app.UIFigure, 'numeric');
            app.B_o.Value = 1.7;
            app.DensidaddelAceitegrcm3EditFieldLabel = uilabel(app.UIFigure);
            app.DensidaddelAceitegrcm3EditFieldLabel.Text = 'Densidad del Aceite [gr/cm^3]';
            app.rho_o = uieditfield(app.UIFigure, 'numeric');
            app.rho_o.Value = 0.98;
            app.DensidaddelAguagrcm3Label = uilabel(app.UIFigure);
            app.DensidaddelAguagrcm3Label.Text = 'Densidad del Agua [gr/cm^3]';
            app.rho_w = uieditfield(app.UIFigure, 'numeric');
            app.rho_w.Value = 1.7;
            app.RadiodedrenemEditFieldLabel = uilabel(app.UIFigure);
            app.RadiodedrenemEditFieldLabel.Text = 'Radio de drene [m]';
            app.r_e = uieditfield(app.UIFigure, 'numeric');
            app.r_e.Value = 300;
            app.RadiodepozomEditFieldLabel = uilabel(app.UIFigure);
            app.RadiodepozomEditFieldLabel.Text = 'Radio de pozo [m]';
            app.r_w = uieditfield(app.UIFigure, 'numeric');
            app.r_w.Value = 0.5;
            app.GastodeaceiteMSTBDEditFieldLabel = uilabel(app.UIFigure);
            app.GastodeaceiteMSTBDEditFieldLabel.Text = 'Gasto de aceite [MSTB/D]';
            app.q_o = uieditfield(app.UIFigure, 'numeric');
            app.q_o.Value = 50;
            app.EsbiencementadoSwitchLabel = uilabel(app.UIFigure);
            app.EsbiencementadoSwitchLabel.Text = '¿Esá bien cementado?';
            app.cementado = uiswitch(app.UIFigure, 'slider');
            app.cementado.Items = {'No', 'Si'};
            app.cementado.Value = 'No';
            app.PermeabilidadfracturaDarcyEditFieldLabel = uilabel(app.UIFigure);
            app.PermeabilidadfracturaDarcyEditFieldLabel.Text = 'Permeabilidad fractura [Darcy]';
            app.k_f = uieditfield(app.UIFigure, 'numeric');
            app.k_f.Value = 0.7;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GrficagastopermeabilidadalturaTab = uitab(app.TabGroup);
            app.GrficagastopermeabilidadalturaTab.Title = 'Gráfica: gasto, permeabilidad, altura';
            app.GastoPermeabilidadAltura = uiaxes(app.GrficagastopermeabilidadalturaTab);
            title(app.GastoPermeabilidadAltura, 'Title')
            xlabel(app.GastoPermeabilidadAltura, 'X')
            ylabel(app.GastoPermeabilidadAltura, 'Y')
            app.q_o_minEditFieldLabel = uilabel(app.GrficagastopermeabilidadalturaTab);
            app.q_o_minEditFieldLabel.Text = 'q_o_min ';
            app.q_o_min_surf = uieditfield(app.GrficagastopermeabilidadalturaTab, 'numeric');
            app.q_o_min_surf.Value = 1;
            app.q_o_maxEditFieldLabel = uilabel(app.GrficagastopermeabilidadalturaTab);
            app.q_o_maxEditFieldLabel.Text = 'q_o_max';
            app.q_o_max_surf = uieditfield(app.GrficagastopermeabilidadalturaTab, 'numeric');
            app.q_o_max_surf.Value = 100;
            app.k_f_minEditFieldLabel = uilabel(app.GrficagastopermeabilidadalturaTab);
            app.k_f_minEditFieldLabel.Text = 'k_f_min';
            app.k_f_min_surf = uieditfield(app.GrficagastopermeabilidadalturaTab, 'numeric');
            app.k_f_min_surf.Value = 0.5;
            app.k_f_maxEditFieldLabel = uilabel(app.GrficagastopermeabilidadalturaTab);
            app.k_f_maxEditFieldLabel.Text = 'k_f_max';
            app.k_f_max_surf = uieditfield(app.GrficagastopermeabilidadalturaTab, 'numeric');
            app.k_f_max_surf.Value = 10;
            app.GastoMSTBDLabel = uilabel(app.GrficagastopermeabilidadalturaTab);
            app.GastoMSTBDLabel.Text = 'Gasto (MSTB/D)';
            app.PermeabilidadfracturadaDarcyLabel = uilabel(app.GrficagastopermeabilidadalturaTab);
            app.PermeabilidadfracturadaDarcyLabel.Text = 'Permeabilidad fracturada (Darcy)';
            app.botonGastoPermeabilidadAltura = uibutton(app.GrficagastopermeabilidadalturaTab, 'push');
            app.botonGastoPermeabilidadAltura.ButtonPushedFcn = createCallbackFcn(app, @botonGastoPermeabilidadAlturaButtonPushed, true);
            app.botonGastoPermeabilidadAltura.Text = 'Calcular gráfica';
            app.Switch = uiswitch(app.GrficagastopermeabilidadalturaTab, 'slider');
            app.Switch.Items = {'Gasto', 'Permeabilidad'};
            app.Switch.Value = 'Gasto';
            app.GrficagastoTab = uitab(app.TabGroup);
            app.GrficagastoTab.Title = 'Gráfica gasto';
            app.graficaGasto = uiaxes(app.GrficagastoTab);
            title(app.graficaGasto, 'Title')
            xlabel(app.graficaGasto, 'X')
            ylabel(app.graficaGasto, 'Y')
            app.GastoMSTBDLabel_2 = uilabel(app.GrficagastoTab);
            app.GastoMSTBDLabel_2.Text = 'Gasto (MSTB/D)';
            app.q_o_minEditField_2Label = uilabel(app.GrficagastoTab);
            app.q_o_minEditField_2Label.Text = 'q_o_min ';
            app.q_o_min_gasto = uieditfield(app.GrficagastoTab, 'numeric');
            app.q_o_min_gasto.Value = 1;
            app.q_o_maxEditField_2Label = uilabel(app.GrficagastoTab);
            app.q_o_maxEditField_2Label.Text = 'q_o_max';
            app.q_o_max_gasto = uieditfield(app.GrficagastoTab, 'numeric');
            app.q_o_max_gasto.Value = 100;
            app.botonGraficaGasto = uibutton(app.GrficagastoTab, 'push');
            app.botonGraficaGasto.ButtonPushedFcn = createCallbackFcn(app, @botonGraficaGastoPushed, true);
            app.botonGraficaGasto.Text = 'Calcular gráfica';
            app.GrficapermeabilidadTab = uitab(app.TabGroup);
            app.GrficapermeabilidadTab.Title = 'Gráfica permeabilidad';
            app.graficaPermeabilidad = uiaxes(app.GrficapermeabilidadTab);
            title(app.graficaPermeabilidad, 'Title')
            xlabel(app.graficaPermeabilidad, 'X')
            ylabel(app.graficaPermeabilidad, 'Y')
            app.PermeabilidadfracturadaDarcyLabel_2 = uilabel(app.GrficapermeabilidadTab);
            app.PermeabilidadfracturadaDarcyLabel_2.Text = 'Permeabilidad fracturada (Darcy)';
            app.k_f_minEditField_2Label = uilabel(app.GrficapermeabilidadTab);
            app.k_f_minEditField_2Label.Text = 'k_f_min';
            app.k_f_min_permeabilidad = uieditfield(app.GrficapermeabilidadTab, 'numeric');
            app.k_f_min_permeabilidad.Value = 0.5;
            app.k_f_maxEditField_2Label = uilabel(app.GrficapermeabilidadTab);
            app.k_f_maxEditField_2Label.Text = 'k_f_max';
            app.k_f_max_permeabilidad = uieditfield(app.GrficapermeabilidadTab, 'numeric');
            app.k_f_max_permeabilidad.Value = 10;
            app.botonGraficaPermeabilidad = uibutton(app.GrficapermeabilidadTab, 'push');
            app.botonGraficaPermeabilidad.ButtonPushedFcn = createCallbackFcn(app, @botonGraficaPermeabilidadButtonPushed, true);
            app.botonGraficaPermeabilidad.Text = 'Calcular gráfica';
            app.Calcularh_wcButton = uibutton(app.UIFigure, 'push');
            app.Calcularh_wcButton.ButtonPushedFcn = createCallbackFcn(app, @Calcularh_wcButtonPushed, true);
            app.Calcularh_wcButton.Text = 'Calcular h_wc';
            app.h_wcEditFieldLabel = uilabel(app.UIFigure);
            app.h_wcEditFieldLabel.Text = 'h_wc';
            app.h_wc = uieditfield(app.UIFigure, 'numeric');
            app.h_wc.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = conificaciondeagua

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end