classdef InterfazGrafica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DatosTab                        matlab.ui.container.Tab
        GradosAPIEditFieldLabel         matlab.ui.control.Label
        Grados_API                      matlab.ui.control.NumericEditField
        PwhPSIALabel                    matlab.ui.control.Label
        Pwh                             matlab.ui.control.NumericEditField
        TRinEditFieldLabel              matlab.ui.control.Label
        Tr                              matlab.ui.control.NumericEditField
        DimetroTpinEditFieldLabel       matlab.ui.control.Label
        Tp                              matlab.ui.control.NumericEditField
        RugosidadinEditFieldLabel       matlab.ui.control.Label
        Rugosidad                       matlab.ui.control.NumericEditField
        DensidadrelativagasLabel        matlab.ui.control.Label
        DensidadR_gas                   matlab.ui.control.NumericEditField
        PresinAtmosfricaEditFieldLabel  matlab.ui.control.Label
        P_atm                           matlab.ui.control.NumericEditField
        LftLabel                        matlab.ui.control.Label
        L                               matlab.ui.control.NumericEditField
        PredeterminadaButton_3          matlab.ui.control.Button
        TemperaturadeCabezaFEditFieldLabel  matlab.ui.control.Label
        T_cabeza                        matlab.ui.control.NumericEditField
        TemperaturadeFondoFEditFieldLabel  matlab.ui.control.Label
        T_fondo                         matlab.ui.control.NumericEditField
        RGAEditFieldLabel               matlab.ui.control.Label
        RGA                             matlab.ui.control.NumericEditField
        PwfEditFieldLabel               matlab.ui.control.Label
        Pwf                             matlab.ui.control.NumericEditField
        PwsEditFieldLabel               matlab.ui.control.Label
        Pws                             matlab.ui.control.NumericEditField
        DensidaddelAireEditFieldLabel   matlab.ui.control.Label
        Densidad_aire                   matlab.ui.control.NumericEditField
        PredeterminadaButton_4          matlab.ui.control.Button
        ViscosidaddelAceiteEditFieldLabel  matlab.ui.control.Label
        Viscosidad_aceite               matlab.ui.control.NumericEditField
        PredeterminadaButton            matlab.ui.control.Button
        DensidadAguaEditFieldLabel      matlab.ui.control.Label
        DensidadR_agua                  matlab.ui.control.NumericEditField
        PredeterminadaButton_2          matlab.ui.control.Button
        nguloLabel                      matlab.ui.control.Label
        Angulo                          matlab.ui.control.NumericEditField
        QopropuestoCEbpdLabel           matlab.ui.control.Label
        Qo_propuesto                    matlab.ui.control.NumericEditField
        QobpdEditFieldLabel             matlab.ui.control.Label
        Qo                              matlab.ui.control.NumericEditField
        ClculoparaungastoTab            matlab.ui.container.Tab
        MtodoLabel                      matlab.ui.control.Label
        Opcion                          matlab.ui.control.ListBox
        CalcularButton                  matlab.ui.control.Button
        PresiondeburbujaLabel           matlab.ui.control.Label
        P_burbuja                       matlab.ui.control.NumericEditField
        OpcionesdeclculoLabel           matlab.ui.control.Label
        IntervalodedistanciaftEditFieldLabel  matlab.ui.control.Label
        DistanciaDivision               matlab.ui.control.NumericEditField
        CalculalapresindesalidacadadeterminadonmerodepiesLabel  matlab.ui.control.Label
        tabla                           matlab.ui.control.Table
        PresindesalidaalamximaprofundidadpsiLabel  matlab.ui.control.Label
        PresionMax                      matlab.ui.control.NumericEditField
        CalculovariosgastosTab          matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        CalculoyTabla                   matlab.ui.container.Tab
        tablaQP                         matlab.ui.control.Table
        Label                           matlab.ui.control.Label
        Q_minEditFieldLabel             matlab.ui.control.Label
        Q_min                           matlab.ui.control.NumericEditField
        Q_maxEditFieldLabel             matlab.ui.control.Label
        Q_max                           matlab.ui.control.NumericEditField
        CalcularButton_2                matlab.ui.control.Button
        IntervaloQSliderLabel           matlab.ui.control.Label
        IntervaloQ                      matlab.ui.control.Slider
        GrficaGastocontraPresionesmximasTab  matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        VLPPozoMultifsicoLabel          matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: PredeterminadaButton
        function PredeterminadaButtonPushed(app, event)
            app.Viscosidad_aceite.Value = 24;
        end

        % Button pushed function: PredeterminadaButton_3
        function PredeterminadaButton_3Pushed(app, event)
            app.P_atm.Value = 14.7;
            
        end

        % Button pushed function: PredeterminadaButton_4
        function PredeterminadaButton_4Pushed(app, event)
            app.Densidad_aire.Value = .0764;
        end

        % Button pushed function: PredeterminadaButton_2
        function PredeterminadaButton_2Pushed(app, event)
            app.DensidadR_agua.Value = 62.4;
        end

        % Value changed function: Opcion
        function OpcionValueChanged(app, event)
            %value = app.Opcion.Value;
            
           
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            %calcula las presiones de salida de una columna de líquido de un pozo mediante iteraciones a partir ade la densidad y de la relacion de solubilidad, de la densidad relativa del gas y de la presión de cabeza
%se calcula el primer punto de burbuja 

%Datos de Entrada 
Grados_API = app.Grados_API.Value;
Pwh = app.Pwh.Value; %Presion de Cabeza
Tr = app.Tr.Value; %Tuberia de revestimiento
Tp = app.Tp.Value; %Tuberia de Produccion
Rugosidad = app.Rugosidad.Value;
DensidadR_gas = app.DensidadR_gas.Value;
L = app.L.Value; %En pies
T_cabeza =  app.T_cabeza.Value;%Farenheit
T_fondo = app.T_fondo.Value ; %Temperatura de Fondo en Farenheit
RGA = app.RGA.Value ; %Unidades m^3/m^3
Qo = app.Qo.Value; %Barriles por dia
Pwf = app.Pwf.Value; %Presion De Fondo Fluyendo en PSIA
Pws = app.Pws.Value; %Presion estatica o superficie en PSIA
P_atm = app.P_atm.Value; %Presion atmosferica en PSIA
Densidad_aire = app.Densidad_aire.Value; %Densidad del Aire en lb//ft^3
Angulo= app.Angulo.Value;
Viscosidad_aceite= app.Viscosidad_aceite.Value ;%En Unidades Centipoise [cp]
Qo_propuestoCE= app.Qo_propuesto.Value ;%Gasto Propuesto en bpd
DensidadR_agua= app.DensidadR_agua.Value;

%Opciones de calculo
%CalculoCorrelaciones = {'Standing', 'Vazquez'}
Opcion = app.Opcion.Value;
%Opcion = 'Standing';

%calcul;os
RsCE = RGA*5.615; % Relación de gas disuelto en solución (petroleo) para condiciones estandar. aproximidamente es una relación de solubilidad de gas, cambia en funcion de la presión y temperatura

%Se calcula la Densidad del aceite a partir de API
Densidad_aceite=141.5/(Grados_API+131.5); 

%Calculo de la Presion de burbuja
P_burbuja = 18.2*( ( (RsCE/DensidadR_gas)^.83) * 10 ^( (0.00091*T_cabeza) - (0.0125*Grados_API) ) - 1.4);


%distancia
DistanciaDivision = app.DistanciaDivision.Value; %el número de pies que el usuario quiere evaluar la nueva presion. verificar que sea menor a L
vectorDistancia = 0:DistanciaDivision:L;
tamVector = length(vectorDistancia);

%presion
aumentoPresionAproxPorPie = .2;%.135; %esta es una condición experimental, está entre 10 y 30, tipicamente 20
aumentoPresionAproxPorDivision = DistanciaDivision * aumentoPresionAproxPorPie; 
P_salida = NaN(tamVector-1 , 1); %este vector comienza en la posición 2 del vector distancia

%temperatura
%vectorTemperatura = NaN(tamVector);
pendienteTemperatura = (T_fondo - T_cabeza) / L;
vectorTemperatura = pendienteTemperatura * vectorDistancia + T_cabeza;

%calcular presiones mediante iteraciones

for i = 1:length(P_salida)
	Error = 100;
	primerIteracionWhile = true;

	while Error > 0.00000001
		%presión
		if i == 1
			P_entrada = Pwh;
		else
			P_entrada = P_salida(i-1);
		end
		%valor semilla presión
		if primerIteracionWhile == true 
			P_supuesta = P_entrada + aumentoPresionAproxPorDivision;
			primerIteracionWhile = false;
		else
			P_supuesta = P_salida(i);
		end

		P_promedio = mean([P_entrada, P_supuesta]);

		%temperatura
		T_entrada = vectorTemperatura(i);
		T_salida = vectorTemperatura(i+1);
		T_promedio = mean([T_entrada, T_salida]);

		%densidad de gas disuelto y solubilidad
		%iteración \

		[Rs, Densidad_gasDisuelto] = FuncionCorrelaciones(Opcion, Grados_API, DensidadR_gas, P_promedio, T_promedio);


		Densidad_gasL = (abs( (RsCE * DensidadR_gas) - Rs * Densidad_gasDisuelto) ) / ( RsCE - Rs );
		
		Ppc = 677 + (15 * Densidad_gasL) - (37.5 * Densidad_gasL^2 );
		Ppr = P_promedio / Ppc;

		Tpc = 168 + 325 * Densidad_gasL - 12.5 * Densidad_gasL^2;
		Tpr = (T_promedio + 460) / Tpc;

		Z = 1 - ( (3.25 * Ppr) / (10 ^ (0.9813 *Tpr) ) ) + ( (0.274 * Ppr^2) / (10 ^ (0.8157 * Tpr) ) );

		Bg = (Z*(T_promedio+460)/P_promedio)*(P_atm/520);
		Rog_CF=(Densidad_gasL*(Densidad_aire/Bg));
		x=3.5+(986/(T_promedio+460))+(0.2896*Densidad_gasL);
		y=2.4-(0.2*x);
		k=((9.4+(0.5792*Densidad_gasL))*(T_promedio+460)^1.5)/((209+(550.24*Densidad_gasL)+(T_promedio+460)));

		Viscosidad_gas=k*((10^(-4))*exp(x*(Densidad_gasL/62.928)^y));
		Bo=0.9759+0.00012*(Rs*((Densidad_gasDisuelto/Densidad_aceite)^0.5)+(1.25*T_promedio))^1.2;
		Ro_aceiteCF=((Densidad_aceite*DensidadR_agua)+(Densidad_gasDisuelto*Densidad_aire*Rs))/Bo;
		A_total=(pi*(Tp/12)^2)/4;
  
		Gasto_aceite_CF= Qo_propuestoCE*Bo*(5.615/1)*(1/86400);
		Gasto_gas_CF=Qo_propuestoCE*(RsCE-Rs)*Bg*(1/86400);
  
		vsl=Gasto_aceite_CF/A_total;
		vsg=Gasto_gas_CF/A_total;
		vm=vsg+vsl;
		Landa_L=Gasto_aceite_CF/(Gasto_aceite_CF+Gasto_gas_CF);
		%Comienza Correlacion HandB
		A=1.071-((0.2218*((vsl+vsg)^2))/(Tp*0.0833333));

		B=vsg/(vsg+vsl);
		DiferenciaBA=B-A;
		Cirq_aceite=(42.4-(0.047*T_entrada)-(0.267*Grados_API))*exp(-0.0007*P_promedio);
		Nlv=1.938*vsl*(Ro_aceiteCF/Cirq_aceite)^0.25;
		Ngv=1.938*vsg*(Ro_aceiteCF/Cirq_aceite)^0.25;

		Nd=((120.872*Tp)/12)*((Ro_aceiteCF/Cirq_aceite)^0.25);

		Nlmu=0.15726*Viscosidad_aceite*(1/(Ro_aceiteCF*Cirq_aceite^3)^0.25);
		Nlc=0.0104*(1.163-exp(-6.407*Nlmu));
		Xmayuscula=(Nlv*Nlc*P_promedio^0.1)/((Ngv^0.575)*Nd*P_atm^0.1);
		RelacionHLPsi=(0.0745*0.000841+1.0725*Xmayuscula^0.884)/(0.000841+Xmayuscula^0.884);
		zi=(Ngv*Nlmu^0.1)/(Nd^2.14);
		Psi=(0.97471888*0.0102865321+1.7467011*zi^3.8462632)/(0.0102865321+zi^3.8462632);
		

		HL=Psi*RelacionHLPsi;
		
		if HL < Landa_L
			%disp('Menor');
			HL = Landa_L;
		end

		Pm=(Rog_CF*(1-HL))+(Ro_aceiteCF*HL);
		Uns=(Viscosidad_gas*(1-Landa_L))+(Viscosidad_aceite*Landa_L);
		NRE=(1488*Pm*vm*(Tp/12))/Uns;

		Ff = (-2 * log10( Rugosidad / (3.71 * Tp) ) ) ^ (-2);
		ErrorFf = 100;

		while  ErrorFf > .00000001
			FfAnterior = Ff;
			Ff = (-2 * log10( Rugosidad / (3.71 * Tp) + 2.514 / ( sqrt(Ff) *NRE ) ) ) ^ (-2); 
			ErrorFf = abs(Ff - FfAnterior);
		end

		RelacionDpDht= (Ff * Pm * vm^2) / (144 * 2 * 32.2* (Tp/12) ) + (Pm * sin( deg2rad(Angulo) )) / 144 ;%este es el bien calculado
		%RelacionDpDht= (Ff * Pm * vm^2) / (144 * 2 * 32.2* (Tp/12) ) + (Pm * sin( Angulo )) / 144 ; %este es el mal calculado (como en Excel)

		DeltaP=RelacionDpDht*DistanciaDivision;
		P_salida(i) = DeltaP + P_entrada;

		Error = abs(P_salida(i) - P_supuesta);%recalcular el error
	end
end

app.P_burbuja.Value=P_burbuja;
app.PresionMax.Value = P_salida(end);

Presion=[P_atm; P_salida];
app.tabla.Data = array2table([vectorDistancia', vectorTemperatura', Presion]);

        end

        % Value changed function: Q_min
        function Q_minValueChanged(app, event)
            value = app.Q_min.Value;
            %disp(value);
            %app.Intervalo.Q.Limits(2)
            %app.IntervaloQ.Limits = [value, app.Intervalo.Q.Limits(2)];
        end

        % Value changed function: Q_max
        function Q_maxValueChanged(app, event)
            value = app.Q_max.Value;
            
            %app.IntervaloQ.Limits = [ app.Intervalo.Q.Limits(1), value];
        end

        % Button pushed function: CalcularButton_2
        function CalcularButton_2Pushed(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %vectorQ=linspace(app.Q_min.Value, app.Q_max.Value, app.IntervaloQ.Value)';
            vectorQ = app.Q_min.Value: app.IntervaloQ.Value: app.Q_max.Value; 
            vectorQ = vectorQ';
            
            %disp(vectorQ);
            vectorPresionesMaximas = NaN(size(vectorQ));
            
            for varMuda = 1:length(vectorQ)
                 %calcula las presiones de salida de una columna de líquido de un pozo mediante iteraciones a partir ade la densidad y de la relacion de solubilidad, de la densidad relativa del gas y de la presión de cabeza
%se calcula el primer punto de burbuja 

%Datos de Entrada 
Grados_API = app.Grados_API.Value;
Pwh = app.Pwh.Value; %Presion de Cabeza
Tr = app.Tr.Value; %Tuberia de revestimiento
Tp = app.Tp.Value; %Tuberia de Produccion
Rugosidad = app.Rugosidad.Value;
DensidadR_gas = app.DensidadR_gas.Value;
L = app.L.Value; %En pies
T_cabeza =  app.T_cabeza.Value;%Farenheit
T_fondo = app.T_fondo.Value ; %Temperatura de Fondo en Farenheit
RGA = app.RGA.Value ; %Unidades m^3/m^3
Qo = app.Qo.Value; %Barriles por dia
Pwf = app.Pwf.Value; %Presion De Fondo Fluyendo en PSIA
Pws = app.Pws.Value; %Presion estatica o superficie en PSIA
P_atm = app.P_atm.Value; %Presion atmosferica en PSIA
Densidad_aire = app.Densidad_aire.Value; %Densidad del Aire en lb//ft^3
Angulo= app.Angulo.Value;
Viscosidad_aceite= app.Viscosidad_aceite.Value ;%En Unidades Centipoise [cp]
Qo_propuestoCE= vectorQ(varMuda);%Gasto Propuesto en bpd
DensidadR_agua= app.DensidadR_agua.Value;

%Opciones de calculo
%CalculoCorrelaciones = {'Standing', 'Vazquez'}
Opcion = app.Opcion.Value;
%Opcion = 'Standing';

%calcul;os
RsCE = RGA*5.615; % Relación de gas disuelto en solución (petroleo) para condiciones estandar. aproximidamente es una relación de solubilidad de gas, cambia en funcion de la presión y temperatura

%Se calcula la Densidad del aceite a partir de API
Densidad_aceite=141.5/(Grados_API+131.5); 

%Calculo de la Presion de burbuja
P_burbuja = 18.2*( ( (RsCE/DensidadR_gas)^.83) * 10 ^( (0.00091*T_cabeza) - (0.0125*Grados_API) ) - 1.4);


%distancia
DistanciaDivision = 100; %app.DistanciaDivision.Value; %el número de pies que el usuario quiere evaluar la nueva presion. verificar que sea menor a L
vectorDistancia = 0:DistanciaDivision:L;
tamVector = length(vectorDistancia);

%presion
aumentoPresionAproxPorPie = .2;%.135; %esta es una condición experimental, está entre 10 y 30, tipicamente 20
aumentoPresionAproxPorDivision = DistanciaDivision * aumentoPresionAproxPorPie; 
P_salida = NaN(tamVector-1 , 1); %este vector comienza en la posición 2 del vector distancia

%temperatura
%vectorTemperatura = NaN(tamVector);
pendienteTemperatura = (T_fondo - T_cabeza) / L;
vectorTemperatura = pendienteTemperatura * vectorDistancia + T_cabeza;

%calcular presiones mediante iteraciones

for i = 1:length(P_salida)
	Error = 100;
	primerIteracionWhile = true;

	while Error > 0.00000001
		%presión
		if i == 1
			P_entrada = Pwh;
		else
			P_entrada = P_salida(i-1);
		end
		%valor semilla presión
		if primerIteracionWhile == true 
			P_supuesta = P_entrada + aumentoPresionAproxPorDivision;
			primerIteracionWhile = false;
		else
			P_supuesta = P_salida(i);
		end

		P_promedio = mean([P_entrada, P_supuesta]);

		%temperatura
		T_entrada = vectorTemperatura(i);
		T_salida = vectorTemperatura(i+1);
		T_promedio = mean([T_entrada, T_salida]);

		%densidad de gas disuelto y solubilidad
		%iteración \

		[Rs, Densidad_gasDisuelto] = FuncionCorrelaciones(Opcion, Grados_API, DensidadR_gas, P_promedio, T_promedio);


		Densidad_gasL = (abs( (RsCE * DensidadR_gas) - Rs * Densidad_gasDisuelto) ) / ( RsCE - Rs );
		
		Ppc = 677 + (15 * Densidad_gasL) - (37.5 * Densidad_gasL^2 );
		Ppr = P_promedio / Ppc;

		Tpc = 168 + 325 * Densidad_gasL - 12.5 * Densidad_gasL^2;
		Tpr = (T_promedio + 460) / Tpc;

		Z = 1 - ( (3.25 * Ppr) / (10 ^ (0.9813 *Tpr) ) ) + ( (0.274 * Ppr^2) / (10 ^ (0.8157 * Tpr) ) );

		Bg = (Z*(T_promedio+460)/P_promedio)*(P_atm/520);
		Rog_CF=(Densidad_gasL*(Densidad_aire/Bg));
		x=3.5+(986/(T_promedio+460))+(0.2896*Densidad_gasL);
		y=2.4-(0.2*x);
		k=((9.4+(0.5792*Densidad_gasL))*(T_promedio+460)^1.5)/((209+(550.24*Densidad_gasL)+(T_promedio+460)));

		Viscosidad_gas=k*((10^(-4))*exp(x*(Densidad_gasL/62.928)^y));
		Bo=0.9759+0.00012*(Rs*((Densidad_gasDisuelto/Densidad_aceite)^0.5)+(1.25*T_promedio))^1.2;
		Ro_aceiteCF=((Densidad_aceite*DensidadR_agua)+(Densidad_gasDisuelto*Densidad_aire*Rs))/Bo;
		A_total=(pi*(Tp/12)^2)/4;
  
		Gasto_aceite_CF= Qo_propuestoCE*Bo*(5.615/1)*(1/86400);
		Gasto_gas_CF=Qo_propuestoCE*(RsCE-Rs)*Bg*(1/86400);
  
		vsl=Gasto_aceite_CF/A_total;
		vsg=Gasto_gas_CF/A_total;
		vm=vsg+vsl;
		Landa_L=Gasto_aceite_CF/(Gasto_aceite_CF+Gasto_gas_CF);
		%Comienza Correlacion HandB
		A=1.071-((0.2218*((vsl+vsg)^2))/(Tp*0.0833333));

		B=vsg/(vsg+vsl);
		DiferenciaBA=B-A;
		Cirq_aceite=(42.4-(0.047*T_entrada)-(0.267*Grados_API))*exp(-0.0007*P_promedio);
		Nlv=1.938*vsl*(Ro_aceiteCF/Cirq_aceite)^0.25;
		Ngv=1.938*vsg*(Ro_aceiteCF/Cirq_aceite)^0.25;

		Nd=((120.872*Tp)/12)*((Ro_aceiteCF/Cirq_aceite)^0.25);

		Nlmu=0.15726*Viscosidad_aceite*(1/(Ro_aceiteCF*Cirq_aceite^3)^0.25);
		Nlc=0.0104*(1.163-exp(-6.407*Nlmu));
		Xmayuscula=(Nlv*Nlc*P_promedio^0.1)/((Ngv^0.575)*Nd*P_atm^0.1);
		RelacionHLPsi=(0.0745*0.000841+1.0725*Xmayuscula^0.884)/(0.000841+Xmayuscula^0.884);
		zi=(Ngv*Nlmu^0.1)/(Nd^2.14);
		Psi=(0.97471888*0.0102865321+1.7467011*zi^3.8462632)/(0.0102865321+zi^3.8462632);
		

		HL=Psi*RelacionHLPsi;
		
		if HL < Landa_L
			%disp('Menor');
			HL = Landa_L;
		end

		Pm=(Rog_CF*(1-HL))+(Ro_aceiteCF*HL);
		Uns=(Viscosidad_gas*(1-Landa_L))+(Viscosidad_aceite*Landa_L);
		NRE=(1488*Pm*vm*(Tp/12))/Uns;

		Ff = (-2 * log10( Rugosidad / (3.71 * Tp) ) ) ^ (-2);
		ErrorFf = 100;

		while  ErrorFf > .00000001
			FfAnterior = Ff;
			Ff = (-2 * log10( Rugosidad / (3.71 * Tp) + 2.514 / ( sqrt(Ff) *NRE ) ) ) ^ (-2); 
			ErrorFf = abs(Ff - FfAnterior);
		end

		RelacionDpDht= (Ff * Pm * vm^2) / (144 * 2 * 32.2* (Tp/12) ) + (Pm * sin( deg2rad(Angulo) )) / 144 ;%este es el bien calculado
		%RelacionDpDht= (Ff * Pm * vm^2) / (144 * 2 * 32.2* (Tp/12) ) + (Pm * sin( Angulo )) / 144 ; %este es el mal calculado (como en Excel)

		DeltaP=RelacionDpDht*DistanciaDivision;
		P_salida(i) = DeltaP + P_entrada;

		Error = abs(P_salida(i) - P_supuesta);%recalcular el error
	end
end

vectorPresionesMaximas(varMuda) = P_salida(end);


            end
            
app.tablaQP.Data = array2table([vectorQ, vectorPresionesMaximas]);


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.UIAxes.cla;
            ax = app.UIAxes;
            
            plot(ax, vectorQ, vectorPresionesMaximas, 'linewidth',2);
            
            title(ax,"VLP", 'fontsize',16)
            xlabel(ax,"Q [bpd]",'fontsize',12)
            ylabel(ax,"P [psi]", 'fontsize',12)
            %legend(ax,{'Bomba', 'Ducto'}, 'fontsize',14);

        end

        % Callback function
        function MostrarGrficaButtonPushed(app, event)
            app.UIAxes.cla;
            ax = app.UIAxes;
                      
            Qmin = app.Q_min.Value;
            Qmax = app.Q_max.Value;
            
            NPSH = app.NPSH.Value;

            VectorQ=linspace(Qmin,Qmax,250);
            VectorH=FuncionCargaH(A,B,C,VectorQ);
            
            QEsp = app.Q_interseccion.Value;
            kAprox = A + B/QEsp + C/QEsp^2 - NPSH/QEsp^2;
            
            VectorHDucto = funcionCaracteristicaDuctos (kAprox,VectorQ, NPSH);
            
            plot(ax, VectorQ,VectorH, VectorQ, VectorHDucto, 'linewidth',2);
            
            title(ax,"Ecuación carácteristica de bomba y sistema de ductos", 'fontsize',20)
            xlabel(ax,"Q [m^3/s]",'fontsize',12)
            ylabel(ax,"H [m]", 'fontsize',12)
            legend(ax,{'Bomba', 'Ducto'}, 'fontsize',14);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatosTab = uitab(app.TabGroup);
            app.DatosTab.Title = 'Datos';
            app.GradosAPIEditFieldLabel = uilabel(app.DatosTab);
            app.GradosAPIEditFieldLabel.Text = 'Grados API';
            app.Grados_API = uieditfield(app.DatosTab, 'numeric');
            app.Grados_API.Value = 23;
            app.PwhPSIALabel = uilabel(app.DatosTab);
            app.PwhPSIALabel.Text = 'Pwh [PSIA]';
            app.Pwh = uieditfield(app.DatosTab, 'numeric');
            app.Pwh.Value = 135;
            app.TRinEditFieldLabel = uilabel(app.DatosTab);
            app.TRinEditFieldLabel.Text = 'TR  [in]';
            app.Tr = uieditfield(app.DatosTab, 'numeric');
            app.Tr.Value = 7;
            app.DimetroTpinEditFieldLabel = uilabel(app.DatosTab);
            app.DimetroTpinEditFieldLabel.Text = 'Diámetro Tp [in]';
            app.Tp = uieditfield(app.DatosTab, 'numeric');
            app.Tp.Value = 5.5;
            app.RugosidadinEditFieldLabel = uilabel(app.DatosTab);
            app.RugosidadinEditFieldLabel.Text = 'Rugosidad [in] ';
            app.Rugosidad = uieditfield(app.DatosTab, 'numeric');
            app.Rugosidad.Value = 0.0006;
            app.DensidadrelativagasLabel = uilabel(app.DatosTab);
            app.DensidadrelativagasLabel.Text = 'Densidad relativa gas';
            app.DensidadR_gas = uieditfield(app.DatosTab, 'numeric');
            app.DensidadR_gas.Value = 0.7;
            app.PresinAtmosfricaEditFieldLabel = uilabel(app.DatosTab);
            app.PresinAtmosfricaEditFieldLabel.Text = 'Presión Atmosférica';
            app.P_atm = uieditfield(app.DatosTab, 'numeric');
            app.P_atm.Value = 14.7;
            app.LftLabel = uilabel(app.DatosTab);
            app.LftLabel.Text = 'L  [ft]';
            app.L = uieditfield(app.DatosTab, 'numeric');
            app.L.Value = 6800;
            app.PredeterminadaButton_3 = uibutton(app.DatosTab, 'push');
            app.PredeterminadaButton_3.ButtonPushedFcn = createCallbackFcn(app, @PredeterminadaButton_3Pushed, true);
            app.PredeterminadaButton_3.Text = 'Predeterminada';
            app.TemperaturadeCabezaFEditFieldLabel = uilabel(app.DatosTab);
            app.TemperaturadeCabezaFEditFieldLabel.Text = 'Temperatura de Cabeza [F]';
            app.T_cabeza = uieditfield(app.DatosTab, 'numeric');
            app.T_cabeza.Value = 60;
            app.TemperaturadeFondoFEditFieldLabel = uilabel(app.DatosTab);
            app.TemperaturadeFondoFEditFieldLabel.Text = 'Temperatura de  Fondo [F]';
            app.T_fondo = uieditfield(app.DatosTab, 'numeric');
            app.T_fondo.Value = 230;
            app.RGAEditFieldLabel = uilabel(app.DatosTab);
            app.RGAEditFieldLabel.Text = 'RGA';
            app.RGA = uieditfield(app.DatosTab, 'numeric');
            app.RGA.Value = 54;
            app.PwfEditFieldLabel = uilabel(app.DatosTab);
            app.PwfEditFieldLabel.Text = 'Pwf';
            app.Pwf = uieditfield(app.DatosTab, 'numeric');
            app.Pwf.Value = 2487;
            app.PwsEditFieldLabel = uilabel(app.DatosTab);
            app.PwsEditFieldLabel.Text = 'Pws';
            app.Pws = uieditfield(app.DatosTab, 'numeric');
            app.Pws.Value = 2510;
            app.DensidaddelAireEditFieldLabel = uilabel(app.DatosTab);
            app.DensidaddelAireEditFieldLabel.Text = 'Densidad del Aire';
            app.Densidad_aire = uieditfield(app.DatosTab, 'numeric');
            app.Densidad_aire.Value = 0.0764;
            app.PredeterminadaButton_4 = uibutton(app.DatosTab, 'push');
            app.PredeterminadaButton_4.ButtonPushedFcn = createCallbackFcn(app, @PredeterminadaButton_4Pushed, true);
            app.PredeterminadaButton_4.Text = 'Predeterminada';
            app.ViscosidaddelAceiteEditFieldLabel = uilabel(app.DatosTab);
            app.ViscosidaddelAceiteEditFieldLabel.Text = 'Viscosidad del Aceite';
            app.Viscosidad_aceite = uieditfield(app.DatosTab, 'numeric');
            app.Viscosidad_aceite.Value = 24;
            app.PredeterminadaButton = uibutton(app.DatosTab, 'push');
            app.PredeterminadaButton.ButtonPushedFcn = createCallbackFcn(app, @PredeterminadaButtonPushed, true);
            app.PredeterminadaButton.Text = 'Predeterminada';
            app.DensidadAguaEditFieldLabel = uilabel(app.DatosTab);
            app.DensidadAguaEditFieldLabel.Text = 'Densidad Agua';
            app.DensidadR_agua = uieditfield(app.DatosTab, 'numeric');
            app.DensidadR_agua.Value = 62.4;
            app.PredeterminadaButton_2 = uibutton(app.DatosTab, 'push');
            app.PredeterminadaButton_2.ButtonPushedFcn = createCallbackFcn(app, @PredeterminadaButton_2Pushed, true);
            app.PredeterminadaButton_2.Text = 'Predeterminada';
            app.nguloLabel = uilabel(app.DatosTab);
            app.nguloLabel.Text = 'Ángulo [°]';
            app.Angulo = uieditfield(app.DatosTab, 'numeric');
            app.Angulo.Value = 90;
            app.QopropuestoCEbpdLabel = uilabel(app.DatosTab);
            app.QopropuestoCEbpdLabel.Text = ' Qo propuesto @CE [bpd]';
            app.Qo_propuesto = uieditfield(app.DatosTab, 'numeric');
            app.Qo_propuesto.Value = 5000;
            app.QobpdEditFieldLabel = uilabel(app.DatosTab);
            app.QobpdEditFieldLabel.Text = 'Qo [bpd]';
            app.Qo = uieditfield(app.DatosTab, 'numeric');
            app.Qo.Value = 5000;
            app.ClculoparaungastoTab = uitab(app.TabGroup);
            app.ClculoparaungastoTab.Title = 'Cálculo para un gasto';
            app.MtodoLabel = uilabel(app.ClculoparaungastoTab);
            app.MtodoLabel.Text = 'Método';
            app.Opcion = uilistbox(app.ClculoparaungastoTab);
            app.Opcion.Items = {'Vazquez', 'Standing'};
            app.Opcion.ValueChangedFcn = createCallbackFcn(app, @OpcionValueChanged, true);
            app.Opcion.Value = 'Vazquez';
            app.CalcularButton = uibutton(app.ClculoparaungastoTab, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.PresiondeburbujaLabel = uilabel(app.ClculoparaungastoTab);
            app.PresiondeburbujaLabel.Text = 'Presion de  burbuja';
            app.P_burbuja = uieditfield(app.ClculoparaungastoTab, 'numeric');
            app.P_burbuja.Editable = 'off';
            app.OpcionesdeclculoLabel = uilabel(app.ClculoparaungastoTab);
            app.OpcionesdeclculoLabel.Text = 'Opciones de cálculo';
            app.IntervalodedistanciaftEditFieldLabel = uilabel(app.ClculoparaungastoTab);
            app.IntervalodedistanciaftEditFieldLabel.Text = 'Intervalo de distancia [ft]';
            app.DistanciaDivision = uieditfield(app.ClculoparaungastoTab, 'numeric');
            app.DistanciaDivision.Value = 100;
            app.CalculalapresindesalidacadadeterminadonmerodepiesLabel = uilabel(app.ClculoparaungastoTab);
            app.CalculalapresindesalidacadadeterminadonmerodepiesLabel.Text = 'Calcula la presión de salida cada determinado número de pies';
            app.tabla = uitable(app.ClculoparaungastoTab);
            app.tabla.ColumnName = {'Profundidad[ft]'; 'Temperatura [°F]'; 'Presión de salida [psi]'};
            app.tabla.RowName = {};
            app.PresindesalidaalamximaprofundidadpsiLabel = uilabel(app.ClculoparaungastoTab);
            app.PresindesalidaalamximaprofundidadpsiLabel.Text = {'Presión de salida a la '; 'máxima profundidad [psi] '};
            app.PresionMax = uieditfield(app.ClculoparaungastoTab, 'numeric');
            app.PresionMax.Editable = 'off';
            app.CalculovariosgastosTab = uitab(app.TabGroup);
            app.CalculovariosgastosTab.Title = 'Calculo varios gastos';
            app.TabGroup2 = uitabgroup(app.CalculovariosgastosTab);
            app.CalculoyTabla = uitab(app.TabGroup2);
            app.CalculoyTabla.Title = 'Calculo y Tabla';
            app.tablaQP = uitable(app.CalculoyTabla);
            app.tablaQP.ColumnName = {'Qo (gasto propuesto) [bpd]'; 'Presión máxima [Psi]'};
            app.tablaQP.RowName = {};
            app.Label = uilabel(app.CalculoyTabla);
            app.Label.Text = 'Calcula las presiones máximas para distintos gastos propuestos en intervalos dados por el usario';
            app.Q_minEditFieldLabel = uilabel(app.CalculoyTabla);
            app.Q_minEditFieldLabel.Text = 'Q_min';
            app.Q_min = uieditfield(app.CalculoyTabla, 'numeric');
            app.Q_min.ValueChangedFcn = createCallbackFcn(app, @Q_minValueChanged, true);
            app.Q_min.Value = 100;
            app.Q_maxEditFieldLabel = uilabel(app.CalculoyTabla);
            app.Q_maxEditFieldLabel.Text = 'Q_max';
            app.Q_max = uieditfield(app.CalculoyTabla, 'numeric');
            app.Q_max.ValueChangedFcn = createCallbackFcn(app, @Q_maxValueChanged, true);
            app.Q_max.Value = 5000;
            app.CalcularButton_2 = uibutton(app.CalculoyTabla, 'push');
            app.CalcularButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.CalcularButton_2.Text = 'Calcular';
            app.IntervaloQSliderLabel = uilabel(app.CalculoyTabla);
            app.IntervaloQSliderLabel.Text = 'Intervalo Q';
            app.IntervaloQ = uislider(app.CalculoyTabla);
            app.IntervaloQ.Limits = [50 500];
            app.IntervaloQ.Value = 150;
            app.GrficaGastocontraPresionesmximasTab = uitab(app.TabGroup2);
            app.GrficaGastocontraPresionesmximasTab.Title = 'Gráfica Gasto contra Presiones máximas';
            app.UIAxes = uiaxes(app.GrficaGastocontraPresionesmximasTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.VLPPozoMultifsicoLabel = uilabel(app.UIFigure);
            app.VLPPozoMultifsicoLabel.Text = 'VLP Pozo Multifásico';
        end
    end

    methods (Access = public)

        % Construct app
        function app = InterfazGrafica

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end